classdef rocket_sizing_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        EngineContour                 matlab.ui.control.UIAxes
        SaveCurrentStateButton        matlab.ui.control.Button
        ApplyDimensionstoAllRocketsButton  matlab.ui.control.Button
        CurrentChemistryPanel         matlab.ui.container.Panel
        aeatLabel                     matlab.ui.control.Label
        aeatValue                     matlab.ui.control.Label
        tLabel                        matlab.ui.control.Label
        tValue                        matlab.ui.control.Label
        cpLabel                       matlab.ui.control.Label
        cpValue                       matlab.ui.control.Label
        ispLabel                      matlab.ui.control.Label
        ispValue                      matlab.ui.control.Label
        pLabel                        matlab.ui.control.Label
        pValue                        matlab.ui.control.Label
        mLabel                        matlab.ui.control.Label
        mValue                        matlab.ui.control.Label
        gamLabel                      matlab.ui.control.Label
        gamValue                      matlab.ui.control.Label
        aeLabel                       matlab.ui.control.Label
        aeValue                       matlab.ui.control.Label
        mm2Label                      matlab.ui.control.Label
        KLabel                        matlab.ui.control.Label
        JkgkLabel                     matlab.ui.control.Label
        ratioLabel                    matlab.ui.control.Label
        msLabel                       matlab.ui.control.Label
        atmLabel                      matlab.ui.control.Label
        gmolLabel                     matlab.ui.control.Label
        ratioLabel_2                  matlab.ui.control.Label
        RocketConfigurationEditFieldLabel  matlab.ui.control.Label
        RocketConfigurationEditField  matlab.ui.control.NumericEditField
        ParametersPanel               matlab.ui.container.Panel
        mdotkgSpinnerLabel            matlab.ui.control.Label
        mdotkgSpinner                 matlab.ui.control.Spinner
        LmSpinnerLabel                matlab.ui.control.Label
        LmSpinner                     matlab.ui.control.Spinner
        ChamberConvergeAngledegSpinnerLabel  matlab.ui.control.Label
        ChamberAngleSpinner           matlab.ui.control.Spinner
        ChamberDiametermSpinnerLabel  matlab.ui.control.Label
        ChamberDiameterSpinner        matlab.ui.control.Spinner
        Panel                         matlab.ui.container.Panel
        isp_sLabel                    matlab.ui.control.Label
        isp_s                         matlab.ui.control.Label
        secLabel                      matlab.ui.control.Label
        rbarLabel                     matlab.ui.control.Label
        rbar                          matlab.ui.control.Label
        specificgasconstantLabel      matlab.ui.control.Label
        a_thrLabel                    matlab.ui.control.Label
        a_thr                         matlab.ui.control.Label
        m2Label_2                     matlab.ui.control.Label
        a_nozLabel                    matlab.ui.control.Label
        a_noz                         matlab.ui.control.Label
        m2Label                       matlab.ui.control.Label
        d_thrLabel                    matlab.ui.control.Label
        d_thr                         matlab.ui.control.Label
        mLabel_3                      matlab.ui.control.Label
        d_nozLabel                    matlab.ui.control.Label
        d_noz                         matlab.ui.control.Label
        mLabel_2                      matlab.ui.control.Label
        thrustLabel                   matlab.ui.control.Label
        thrust                        matlab.ui.control.Label
        NLabel                        matlab.ui.control.Label
        ch_volLabel                   matlab.ui.control.Label
        ch_vol                        matlab.ui.control.Label
        m3Label                       matlab.ui.control.Label
        ch_lengthLabel                matlab.ui.control.Label
        ch_len                        matlab.ui.control.Label
        mLabel_4                      matlab.ui.control.Label
    end


    properties (Access = private)
        chemistry; % Description
        rockets;
        mdot;
        Lstar;
        cur_rocket;
        chamber_angle;
        chamber_diameter;
    end

    methods (Access = private)
    
        function update(app)
            app.rockets(app.cur_rocket).update(app.mdot, app.Lstar, app.chamber_angle, app.chamber_diameter);
            
            app.isp_s.Text = num2str(app.rockets(app.cur_rocket).isp_s);
            app.rbar.Text = num2str(app.rockets(app.cur_rocket).rbar);
            app.a_noz.Text = num2str(app.rockets(app.cur_rocket).a_noz);
            app.a_thr.Text = num2str(app.rockets(app.cur_rocket).a_thr);
            app.d_noz.Text = num2str(app.rockets(app.cur_rocket).d_noz);
            app.d_thr.Text = num2str(app.rockets(app.cur_rocket).d_thr);
            app.thrust.Text = num2str(app.rockets(app.cur_rocket).thrust);
            app.ch_vol.Text = num2str(app.rockets(app.cur_rocket).chamber_volume);
            
            value = app.cur_rocket;
            
            app.aeatValue.Text = num2str(app.chemistry(value).aeat);
            app.tValue.Text = num2str(app.chemistry(value).t);
            app.cpValue.Text = num2str(app.chemistry(value).cp);
            app.aeValue.Text = num2str(app.chemistry(value).ae);
            app.ispValue.Text = num2str(app.chemistry(value).isp);
            app.pValue.Text = num2str(app.chemistry(value).p);
            app.mValue.Text = num2str(app.chemistry(value).m);
            app.gamValue.Text = num2str(app.chemistry(value).gam);
            
            draw(app);
            
        end
        
        function draw(app)
                       
            % app.EngineContour.cla;
            app.EngineContour.XLim = [-0.2 0.5];
            app.EngineContour.YLim = [-0.3 0.3];
                
            % trace_x = [];
            % trace_y = [];
            
            ch_rad = app.chamber_diameter/2;
            ch_leng = app.rockets(app.cur_rocket).chamber_length;
            con_len = app.rockets(app.cur_rocket).converging_length;
            thr_rad = app.rockets(app.cur_rocket).d_thr/2;
            noz_rad = app.rockets(app.cur_rocket).d_noz/2;
                    
            trace_y = [0 ch_rad ch_rad  thr_rad];
            trace_x = [0 0      ch_leng con_len];
            
            trace_y_bot = -1.*trace_y;
            trace_y = fliplr(trace_y);
            
            trace_x_bot = trace_x;
            trace_x = fliplr(trace_x);
            
            trace_x = horzcat(trace_x, trace_x_bot);
            trace_y = horzcat(trace_y, trace_y_bot);
            
            % trace = [trace_x; trace_y];
            
            plot(app.EngineContour, trace_x, trace_y);
                        
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            table = readtable('test.xlsx');
            
            app.chemistry = Chemistry.empty(0,height(table));
            for i = 1:height(table)
                app.chemistry(i) = Chemistry();
            end
            
            for i = 1:height(table)
                app.chemistry(i).aeat = table{i,2};
                app.chemistry(i).t = table{i,2};
                app.chemistry(i).cp = table{i,3};
                app.chemistry(i).ae = table{i,4};
                app.chemistry(i).isp = table{i,5};
                app.chemistry(i).p = table{i,6};
                app.chemistry(i).m = table{i,7};
                app.chemistry(i).gam = table{i,8};
            end
            
            %single mdot
            app.mdot = app.mdotkgSpinner.Value; % input('mdot (kg/s): ');
            app.Lstar = app.LmSpinner.Value; % input('Input L* (m): ');
            app.chamber_angle = app.ChamberAngleSpinner.Value;
            app.chamber_diameter = app.ChamberDiameterSpinner.Value;
            
            
            app.rockets = Rocket.empty(0,height(table));
            for i = 1:height(table)
                app.rockets(i) = Rocket(app.chemistry(i),app.mdot, app.Lstar, app.chamber_angle, app.chamber_diameter);
            end
        end

        % Value changed function: RocketConfigurationEditField
        function RocketConfigurationEditFieldValueChanged(app, event)
            app.cur_rocket = app.RocketConfigurationEditField.Value;          
            update(app);            
        end

        % Value changed function: mdotkgSpinner
        function mdotkgSpinnerValueChanged(app, event)
            app.mdot = app.mdotkgSpinner.Value;
            update(app);
        end

        % Value changed function: ChamberAngleSpinner
        function ChamberAngleSpinnerValueChanged(app, event)
            app.chamber_angle = app.ChamberAngleSpinner.Value;
            update(app);            
        end

        % Value changed function: ChamberDiameterSpinner
        function ChamberDiameterSpinnerValueChanged(app, event)
            app.chamber_diameter = app.ChamberDiameterSpinner.Value;
            update(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.EngineContour = uiaxes(app.UIFigure);
            title(app.EngineContour, 'Engine Contour')
            xlabel(app.EngineContour, 'X')
            ylabel(app.EngineContour, 'Y')
            app.SaveCurrentStateButton = uibutton(app.UIFigure, 'push');
            app.SaveCurrentStateButton.Text = 'Save Current State';
            app.ApplyDimensionstoAllRocketsButton = uibutton(app.UIFigure, 'push');
            app.ApplyDimensionstoAllRocketsButton.Text = 'Apply Dimensions to All Rockets';
            app.CurrentChemistryPanel = uipanel(app.UIFigure);
            app.CurrentChemistryPanel.Title = 'Current Chemistry';
            app.aeatLabel = uilabel(app.CurrentChemistryPanel);
            app.aeatLabel.Text = 'aeat';
            app.aeatValue = uilabel(app.CurrentChemistryPanel);
            app.tLabel = uilabel(app.CurrentChemistryPanel);
            app.tLabel.Text = 't';
            app.tValue = uilabel(app.CurrentChemistryPanel);
            app.cpLabel = uilabel(app.CurrentChemistryPanel);
            app.cpLabel.Text = 'cp';
            app.cpValue = uilabel(app.CurrentChemistryPanel);
            app.ispLabel = uilabel(app.CurrentChemistryPanel);
            app.ispLabel.Text = 'isp';
            app.ispValue = uilabel(app.CurrentChemistryPanel);
            app.pLabel = uilabel(app.CurrentChemistryPanel);
            app.pLabel.Text = 'p';
            app.pValue = uilabel(app.CurrentChemistryPanel);
            app.mLabel = uilabel(app.CurrentChemistryPanel);
            app.mLabel.Text = 'm';
            app.mValue = uilabel(app.CurrentChemistryPanel);
            app.gamLabel = uilabel(app.CurrentChemistryPanel);
            app.gamLabel.Text = 'gam';
            app.gamValue = uilabel(app.CurrentChemistryPanel);
            app.aeLabel = uilabel(app.CurrentChemistryPanel);
            app.aeLabel.Text = 'ae';
            app.aeValue = uilabel(app.CurrentChemistryPanel);
            app.mm2Label = uilabel(app.CurrentChemistryPanel);
            app.mm2Label.Text = 'mm2';
            app.KLabel = uilabel(app.CurrentChemistryPanel);
            app.KLabel.Text = 'K';
            app.JkgkLabel = uilabel(app.CurrentChemistryPanel);
            app.JkgkLabel.Text = 'J/kg*k';
            app.ratioLabel = uilabel(app.CurrentChemistryPanel);
            app.ratioLabel.Text = 'ratio';
            app.msLabel = uilabel(app.CurrentChemistryPanel);
            app.msLabel.Text = 'm/s';
            app.atmLabel = uilabel(app.CurrentChemistryPanel);
            app.atmLabel.Text = 'atm';
            app.gmolLabel = uilabel(app.CurrentChemistryPanel);
            app.gmolLabel.Text = 'g/mol';
            app.ratioLabel_2 = uilabel(app.CurrentChemistryPanel);
            app.ratioLabel_2.Text = 'ratio';
            app.RocketConfigurationEditFieldLabel = uilabel(app.UIFigure);
            app.RocketConfigurationEditFieldLabel.Text = 'Rocket Configuration';
            app.RocketConfigurationEditField = uieditfield(app.UIFigure, 'numeric');
            app.RocketConfigurationEditField.ValueChangedFcn = createCallbackFcn(app, @RocketConfigurationEditFieldValueChanged, true);
            app.RocketConfigurationEditField.Value = 1;
            app.ParametersPanel = uipanel(app.UIFigure);
            app.ParametersPanel.Title = 'Parameters';
            app.mdotkgSpinnerLabel = uilabel(app.ParametersPanel);
            app.mdotkgSpinnerLabel.Text = 'mdot (kg)';
            app.mdotkgSpinner = uispinner(app.ParametersPanel);
            app.mdotkgSpinner.Step = 0.1;
            app.mdotkgSpinner.Limits = [0 Inf];
            app.mdotkgSpinner.ValueChangedFcn = createCallbackFcn(app, @mdotkgSpinnerValueChanged, true);
            app.mdotkgSpinner.Value = 1;
            app.LmSpinnerLabel = uilabel(app.ParametersPanel);
            app.LmSpinnerLabel.Text = 'L* (m)';
            app.LmSpinner = uispinner(app.ParametersPanel);
            app.LmSpinner.Step = 0.01;
            app.LmSpinner.Limits = [0 Inf];
            app.LmSpinner.Value = 0.11;
            app.ChamberConvergeAngledegSpinnerLabel = uilabel(app.ParametersPanel);
            app.ChamberConvergeAngledegSpinnerLabel.Text = 'Chamber Converge Angle (deg)';
            app.ChamberAngleSpinner = uispinner(app.ParametersPanel);
            app.ChamberAngleSpinner.ValueChangedFcn = createCallbackFcn(app, @ChamberAngleSpinnerValueChanged, true);
            app.ChamberAngleSpinner.Value = 30;
            app.ChamberDiametermSpinnerLabel = uilabel(app.ParametersPanel);
            app.ChamberDiametermSpinnerLabel.Text = 'Chamber Diameter (m)';
            app.ChamberDiameterSpinner = uispinner(app.ParametersPanel);
            app.ChamberDiameterSpinner.Step = 0.01;
            app.ChamberDiameterSpinner.ValueChangedFcn = createCallbackFcn(app, @ChamberDiameterSpinnerValueChanged, true);
            app.ChamberDiameterSpinner.Value = 0.25;
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.isp_sLabel = uilabel(app.Panel);
            app.isp_sLabel.Text = 'isp_s';
            app.isp_s = uilabel(app.Panel);
            app.secLabel = uilabel(app.Panel);
            app.secLabel.Text = 'sec';
            app.rbarLabel = uilabel(app.Panel);
            app.rbarLabel.Text = 'rbar';
            app.rbar = uilabel(app.Panel);
            app.specificgasconstantLabel = uilabel(app.Panel);
            app.specificgasconstantLabel.Text = 'specific gas constant';
            app.a_thrLabel = uilabel(app.Panel);
            app.a_thrLabel.Text = 'a_thr';
            app.a_thr = uilabel(app.Panel);
            app.m2Label_2 = uilabel(app.Panel);
            app.m2Label_2.Text = 'm2';
            app.a_nozLabel = uilabel(app.Panel);
            app.a_nozLabel.Text = 'a_noz';
            app.a_noz = uilabel(app.Panel);
            app.m2Label = uilabel(app.Panel);
            app.m2Label.Text = 'm2';
            app.d_thrLabel = uilabel(app.Panel);
            app.d_thrLabel.Text = 'd_thr';
            app.d_thr = uilabel(app.Panel);
            app.mLabel_3 = uilabel(app.Panel);
            app.mLabel_3.Text = 'm';
            app.d_nozLabel = uilabel(app.Panel);
            app.d_nozLabel.Text = 'd_noz';
            app.d_noz = uilabel(app.Panel);
            app.mLabel_2 = uilabel(app.Panel);
            app.mLabel_2.Text = 'm';
            app.thrustLabel = uilabel(app.Panel);
            app.thrustLabel.Text = 'thrust';
            app.thrust = uilabel(app.Panel);
            app.NLabel = uilabel(app.Panel);
            app.NLabel.Text = 'N';
            app.ch_volLabel = uilabel(app.Panel);
            app.ch_volLabel.Text = 'ch_vol';
            app.ch_vol = uilabel(app.Panel);
            app.m3Label = uilabel(app.Panel);
            app.m3Label.Text = 'm3';
            app.ch_lengthLabel = uilabel(app.Panel);
            app.ch_lengthLabel.Text = 'ch_length';
            app.ch_len = uilabel(app.Panel);
            app.mLabel_4 = uilabel(app.Panel);
            app.mLabel_4.Text = 'm';
        end
    end

    methods (Access = public)

        % Construct app
        function app = rocket_sizing_UI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end