classdef smoothJacketDesignSpace < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        MdotSliderLabel           matlab.ui.control.Label
        MdotSlider                matlab.ui.control.Slider
        ThroatDiameterLabel       matlab.ui.control.Label
        Thr_d_mmLabel             matlab.ui.control.Label
        LowkEditFieldLabel        matlab.ui.control.Label
        LowkEditField             matlab.ui.control.NumericEditField
        HighkEditFieldLabel       matlab.ui.control.Label
        HighkEditField            matlab.ui.control.NumericEditField
        Lowt_wallEditFieldLabel   matlab.ui.control.Label
        Lowt_wallEditField        matlab.ui.control.NumericEditField
        Hight_wallEditFieldLabel  matlab.ui.control.Label
        Hight_wallEditField       matlab.ui.control.NumericEditField
        T_wgSliderLabel           matlab.ui.control.Label
        T_wgSlider                matlab.ui.control.Slider
        mdot_valLabel             matlab.ui.control.Label
        T_wg_valLabel             matlab.ui.control.Label
        T_fuelEditFieldLabel      matlab.ui.control.Label
        T_fuelEditField           matlab.ui.control.NumericEditField
        HeatFluxLabel             matlab.ui.control.Label
        qLabel                    matlab.ui.control.Label
        ManualUpdateButton        matlab.ui.control.Button
        RightPanel                matlab.ui.container.Panel
        DesignSpace               matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        mdot; %keep in mind that this is fuel only
        total_mdot;
        low_k;
        high_k;
        low_t_wall;
        high_t_wall;
        
        h_g = 35.025e3; % W / (m * K)
        T_aw = 3126.56; % K
        T_wg;
        T_wc;
        T_co = 310; % K
        q;
        h_c; % coolant coefficient (driven)
        
        %this is for the whole mdot
        throat_mdot_factor = 0.001740396; %m^2*s/kg
        area_thr;
        d_thr;
    end
    
    methods (Access = private)
        
        function update(app)
            
            app.area_thr = app.throat_mdot_factor*app.total_mdot;
            app.d_thr = sqrt(app.area_thr/pi)*2;
            app.Thr_d_mmLabel.Text = num2str(app.d_thr*1000);
            
%             app.low_k = app.LowkEditField.Value;
%             app.high_k = app.HighkEditField.Value;
%             app.low_t_wall = app.Lowt_wallEditField.Value;
%             app.high_t_wall = app.Hight_wallEditField.Value;
            
            app.q = app.h_g*(app.T_aw-app.T_wg);
            app.qLabel.Text = num2str(app.q);
            
            H = app.q/(app.T_aw - app.T_co);
            
            %step_t = (app.high_t_wall - app.low_t_wall)/100;
            %step_k = (app.high_k - app.low_k)/100;
            
%             [Thick, Conduct] = meshgrid((app.low_t_wall:step_t:app.high_t_wall),...
%                 (app.low_k:step_k:app.high_k));

            Thick = linspace(app.low_t_wall,app.high_t_wall);
            Conduct = linspace(app.low_k,app.high_k);
            
            Thick_col = Thick.^-1;
            Thick_col = Thick_col';
            
            app.h_c = ((1/H)-(1/app.h_g)-(1./(Thick_col*Conduct))).^(-1);
            
%             [X,Y] = meshgrid(-8:.5:8);
%             R = sqrt(X.^2 + Y.^2) + eps;
%             Z = sin(R)./R;
%             mesh(app.DesignSpace,X,Y,Z);
            
            mesh(app.DesignSpace,Thick,Conduct,app.h_c);
            xlabel(app.DesignSpace, 'Wall Thickness (m)')
            ylabel(app.DesignSpace, 'Wall Conductivity (W/(m*K))')
            zlabel(app.DesignSpace, 'Coolant Convection Coefficient (h_c)')
            
        end
%         function update(app)
%             app.DesignSpace = calcSpace(app);
%         end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           app.mdot = app.MdotSlider.Value;
           app.total_mdot = app.mdot*3.2;
           app.T_wg = app.T_wgSlider.Value;
           
           app.low_k = app.LowkEditField.Value;
           app.high_k = app.HighkEditField.Value;
           app.low_t_wall = app.Lowt_wallEditField.Value;
           app.high_t_wall = app.Hight_wallEditField.Value;
           
           app.mdot_valLabel.Text = num2str(app.mdot);
           app.T_wg_valLabel.Text = num2str(app.T_wg);
           
           app.area_thr = app.throat_mdot_factor*app.total_mdot;
           app.d_thr = sqrt(app.area_thr/pi)*2;
           
        end

        % Value changed function: MdotSlider
        function MdotSliderValueChanged(app, event)
            app.mdot = app.MdotSlider.Value;
            app.total_mdot = app.mdot*3.2;
            app.mdot_valLabel.Text = num2str(app.mdot);
            app.update;
        end

        % Value changed function: LowkEditField
        function LowkEditFieldValueChanged(app, event)
            app.low_k = app.LowkEditField.Value;
            app.update;
        end

        % Value changed function: HighkEditField
        function HighkEditFieldValueChanged(app, event)
            app.high_k = app.HighkEditField.Value;
            app.update;
        end

        % Value changed function: Lowt_wallEditField
        function Lowt_wallEditFieldValueChanged(app, event)
            app.low_t_wall = app.Lowt_wallEditField.Value;
            app.update;
        end

        % Value changed function: Hight_wallEditField
        function Hight_wallEditFieldValueChanged(app, event)
            app.high_t_wall = app.Hight_wallEditField.Value;
            app.update;
        end

        % Value changed function: T_wgSlider
        function T_wgSliderValueChanged(app, event)
            app.T_wg = app.T_wgSlider.Value;
            app.T_wg_valLabel.Text = num2str(app.T_wg);
            app.update;
            
        end

        % Value changing function: T_wgSlider
        function T_wgSliderValueChanging(app, event)
            app.T_wg_valLabel.Text = num2str(event.Value);
            
        end

        % Value changing function: MdotSlider
        function MdotSliderValueChanging(app, event)
            app.mdot_valLabel.Text = num2str(event.Value);
            
        end

        % Value changed function: T_fuelEditField
        function T_fuelEditFieldValueChanged(app, event)
            app.T_co = app.T_fuelEditField.Value;
            app.update;
        end

        % Button pushed function: ManualUpdateButton
        function ManualUpdateButtonPushed(app, event)
            app.update;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.MdotSliderLabel = uilabel(app.LeftPanel);
            app.MdotSliderLabel.Text = 'Mdot';
            app.MdotSlider = uislider(app.LeftPanel);
            app.MdotSlider.Limits = [0.1 0.8];
            app.MdotSlider.ValueChangedFcn = createCallbackFcn(app, @MdotSliderValueChanged, true);
            app.MdotSlider.ValueChangingFcn = createCallbackFcn(app, @MdotSliderValueChanging, true);
            app.MdotSlider.Tooltip = {'This mdot is the fuel side only mdot. Whole engine total_mdot = mdot*3.2 (2.2 O/F + 1)'};
            app.MdotSlider.Value = 0.15625;
            app.ThroatDiameterLabel = uilabel(app.LeftPanel);
            app.ThroatDiameterLabel.Text = 'Throat Diameter';
            app.Thr_d_mmLabel = uilabel(app.LeftPanel);
            app.Thr_d_mmLabel.Tooltip = {'mm'};
            app.Thr_d_mmLabel.Text = 'Thr_d_mm';
            app.LowkEditFieldLabel = uilabel(app.LeftPanel);
            app.LowkEditFieldLabel.Text = 'Low k';
            app.LowkEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LowkEditField.ValueChangedFcn = createCallbackFcn(app, @LowkEditFieldValueChanged, true);
            app.LowkEditField.Tooltip = {'These are the graph limits for the 3D space.'};
            app.LowkEditField.Value = 10;
            app.HighkEditFieldLabel = uilabel(app.LeftPanel);
            app.HighkEditFieldLabel.Text = 'High k';
            app.HighkEditField = uieditfield(app.LeftPanel, 'numeric');
            app.HighkEditField.ValueChangedFcn = createCallbackFcn(app, @HighkEditFieldValueChanged, true);
            app.HighkEditField.Value = 1000;
            app.Lowt_wallEditFieldLabel = uilabel(app.LeftPanel);
            app.Lowt_wallEditFieldLabel.Text = 'Low t_wall';
            app.Lowt_wallEditField = uieditfield(app.LeftPanel, 'numeric');
            app.Lowt_wallEditField.ValueChangedFcn = createCallbackFcn(app, @Lowt_wallEditFieldValueChanged, true);
            app.Lowt_wallEditField.Value = 0.0005;
            app.Hight_wallEditFieldLabel = uilabel(app.LeftPanel);
            app.Hight_wallEditFieldLabel.Text = 'High t_wall';
            app.Hight_wallEditField = uieditfield(app.LeftPanel, 'numeric');
            app.Hight_wallEditField.ValueChangedFcn = createCallbackFcn(app, @Hight_wallEditFieldValueChanged, true);
            app.Hight_wallEditField.Value = 0.01;
            app.T_wgSliderLabel = uilabel(app.LeftPanel);
            app.T_wgSliderLabel.Text = 'T_wg';
            app.T_wgSlider = uislider(app.LeftPanel);
            app.T_wgSlider.Limits = [300 1300];
            app.T_wgSlider.ValueChangedFcn = createCallbackFcn(app, @T_wgSliderValueChanged, true);
            app.T_wgSlider.ValueChangingFcn = createCallbackFcn(app, @T_wgSliderValueChanging, true);
            app.T_wgSlider.Tooltip = {'This is for the 2D per T_wg slice graph, does not affect the 3D design space, but will reset it to default isometric view.'};
            app.T_wgSlider.Value = 873;
            app.mdot_valLabel = uilabel(app.LeftPanel);
            app.mdot_valLabel.Text = 'mdot_val';
            app.T_wg_valLabel = uilabel(app.LeftPanel);
            app.T_wg_valLabel.Text = 'T_wg_val';
            app.T_fuelEditFieldLabel = uilabel(app.LeftPanel);
            app.T_fuelEditFieldLabel.Text = 'T_fuel';
            app.T_fuelEditField = uieditfield(app.LeftPanel, 'numeric');
            app.T_fuelEditField.Limits = [0 1000];
            app.T_fuelEditField.ValueChangedFcn = createCallbackFcn(app, @T_fuelEditFieldValueChanged, true);
            app.T_fuelEditField.Tooltip = {'Kelvin'};
            app.T_fuelEditField.Value = 310;
            app.HeatFluxLabel = uilabel(app.LeftPanel);
            app.HeatFluxLabel.Text = 'Heat Flux';
            app.qLabel = uilabel(app.LeftPanel);
            app.qLabel.Tooltip = {'W/(m^2*K)'};
            app.qLabel.Text = 'q';
            app.ManualUpdateButton = uibutton(app.LeftPanel, 'push');
            app.ManualUpdateButton.ButtonPushedFcn = createCallbackFcn(app, @ManualUpdateButtonPushed, true);
            app.ManualUpdateButton.Text = 'Manual Update';
            app.RightPanel = uipanel(app.GridLayout);
            app.DesignSpace = uiaxes(app.RightPanel);
            title(app.DesignSpace, 'Design Space')
            xlabel(app.DesignSpace, 'X')
            ylabel(app.DesignSpace, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = smoothJacketDesignSpace

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end